classdef MatlabProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        StiffDividerLabel           matlab.ui.control.EditField
        StiffDividerEditFieldLabel  matlab.ui.control.Label
        SignalGeneratorLabel        matlab.ui.control.Label
        TransistorLabel             matlab.ui.control.Label
        Zg                          matlab.ui.control.NumericEditField
        SignalImpedanceLabel        matlab.ui.control.Label
        BaseEmitterResistanceLabel  matlab.ui.control.Label
        ImpedanceLabel              matlab.ui.control.Label
        GainLabel                   matlab.ui.control.Label
        InputOutputAmplitudeLabel   matlab.ui.control.Label
        QPointLabel                 matlab.ui.control.Label
        ACLoadlineLabel             matlab.ui.control.Label
        DCLoadlineLabel             matlab.ui.control.Label
        ACsaturation                matlab.ui.control.NumericEditField
        ACsaturationEditFieldLabel  matlab.ui.control.Label
        ACcutoff                    matlab.ui.control.NumericEditField
        ACcutoffEditFieldLabel      matlab.ui.control.Label
        ICsaturation                matlab.ui.control.NumericEditField
        ICsaturationEditFieldLabel  matlab.ui.control.Label
        VCEcutoff                   matlab.ui.control.NumericEditField
        VCEcutoffEditFieldLabel     matlab.ui.control.Label
        VA                          matlab.ui.control.NumericEditField
        VALabel                     matlab.ui.control.Label
        OptimizingQPointLabel       matlab.ui.control.Label
        MaximumOutputSwingLabel     matlab.ui.control.Label
        PowerandEfficiencyLabel     matlab.ui.control.Label
        CurrentDrainLabel           matlab.ui.control.Label
        ACAnalysisLabel             matlab.ui.control.Label
        DCAnalysisLabel             matlab.ui.control.Label
        Vout                        matlab.ui.control.NumericEditField
        VoutLabel                   matlab.ui.control.Label
        Efficiency                  matlab.ui.control.NumericEditField
        EfficiencyEditFieldLabel    matlab.ui.control.Label
        PS                          matlab.ui.control.NumericEditField
        PSEditFieldLabel            matlab.ui.control.Label
        PL                          matlab.ui.control.NumericEditField
        PLEditFieldLabel            matlab.ui.control.Label
        PD                          matlab.ui.control.NumericEditField
        PDEditFieldLabel            matlab.ui.control.Label
        IS                          matlab.ui.control.NumericEditField
        ISLabel                     matlab.ui.control.Label
        Ibias                       matlab.ui.control.NumericEditField
        IbiasEditFieldLabel         matlab.ui.control.Label
        MPP                         matlab.ui.control.NumericEditField
        MPPEditFieldLabel           matlab.ui.control.Label
        REop                        matlab.ui.control.NumericEditField
        REopEditFieldLabel          matlab.ui.control.Label
        Av                          matlab.ui.control.NumericEditField
        AvEditFieldLabel            matlab.ui.control.Label
        Vin                         matlab.ui.control.NumericEditField
        VinLabel                    matlab.ui.control.Label
        Zout                        matlab.ui.control.NumericEditField
        ZoutEditFieldLabel          matlab.ui.control.Label
        Zin                         matlab.ui.control.NumericEditField
        ZinEditFieldLabel           matlab.ui.control.Label
        Zbase                       matlab.ui.control.NumericEditField
        ZbaseEditFieldLabel         matlab.ui.control.Label
        rc                          matlab.ui.control.NumericEditField
        rcEditFieldLabel            matlab.ui.control.Label
        re                          matlab.ui.control.NumericEditField
        reEditFieldLabel            matlab.ui.control.Label
        VCEQ                        matlab.ui.control.NumericEditField
        VCEQEditFieldLabel          matlab.ui.control.Label
        VC                          matlab.ui.control.NumericEditField
        VCEditFieldLabel            matlab.ui.control.Label
        ICEQ                        matlab.ui.control.NumericEditField
        ICEQEditFieldLabel          matlab.ui.control.Label
        VE                          matlab.ui.control.NumericEditField
        VEEditFieldLabel            matlab.ui.control.Label
        VB                          matlab.ui.control.NumericEditField
        VBEditFieldLabel            matlab.ui.control.Label
        RL                          matlab.ui.control.NumericEditField
        LoadLabel                   matlab.ui.control.Label
        RC                          matlab.ui.control.NumericEditField
        RCEditFieldLabel            matlab.ui.control.Label
        RE2                         matlab.ui.control.NumericEditField
        RE2EditFieldLabel           matlab.ui.control.Label
        RE1                         matlab.ui.control.NumericEditField
        RE1EditFieldLabel           matlab.ui.control.Label
        R2                          matlab.ui.control.NumericEditField
        R2EditFieldLabel            matlab.ui.control.Label
        R1                          matlab.ui.control.NumericEditField
        R1EditFieldLabel            matlab.ui.control.Label
        RS                          matlab.ui.control.NumericEditField
        SeriesResistanceLabel       matlab.ui.control.Label
        Beta                        matlab.ui.control.NumericEditField
        BetaEditFieldLabel          matlab.ui.control.Label
        Duration                    matlab.ui.control.NumericEditField
        DurationsEditFieldLabel     matlab.ui.control.Label
        Frequency                   matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel   matlab.ui.control.Label
        Vs                          matlab.ui.control.NumericEditField
        VsVppLabel                  matlab.ui.control.Label
        VCC                         matlab.ui.control.NumericEditField
        VCCLabel                    matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        Button                      matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
           [VB, VE, ICEQ, VC, VCEQ, re, rc, Zbase, Zin, Zout, Vin, Av, Vout, REop, MPP, Ibias, IS, PD, PL, PS, Efficiency, VCEcutoff, ICsaturation, ACcutoff, ACsaturation] = CommonEmitterAnalysis(app.VCC.Value, app.Beta.Value, app.RS.Value, app.R1.Value, app.R2.Value, app.RE1.Value, app.RE2.Value, app.RC.Value, app.RL.Value, app.Zg.Value, app.Vs.Value, app.VA.Value, app.Frequency.Value, app.Duration.Value);
           
           app.VB.Value           = VB; 
           app.VE.Value           = VE; 
           app.ICEQ.Value         = ICEQ; 
           app.VC.Value           = VC; 
           app.VCEQ.Value         = VCEQ; 
           app.re.Value           = re; 
           app.rc.Value           = rc; 
           app.Zbase.Value        = Zbase; 
           app.Zin.Value          = Zin; 
           app.Zout.Value         = Zout; 
           app.Vin.Value          = Vin; 
           app.Av.Value           = Av; 
           app.Vout.Value         = Vout; 
           app.REop.Value         = REop; 
           app.MPP.Value          = MPP; 
           app.Ibias.Value        = Ibias; 
           app.IS.Value           = IS; 
           app.PD.Value           = PD; 
           app.PL.Value           = PL; 
           app.PS.Value           = PS; 
           app.Efficiency.Value   = Efficiency; 
           app.VCEcutoff.Value    = VCEcutoff; 
           app.ICsaturation.Value = ICsaturation; 
           app.ACcutoff.Value     = ACcutoff; 
           app.ACsaturation.Value = ACsaturation;

           label = app.StiffDividerLabel.Value;

           % Determining is there is a stiff voltage divider 
           % β • (RE1+RE2) ≥ 10 • (R1//R2) 
           A = app.Beta.Value.*(app.RE1.Value+app.RE2.Value);  
           B = 10.*(1/((1/app.R1.Value)+(1/app.R2.Value)));
           StiffDivider = A >= B;

           if StiffDivider == 0
                app.StiffDividerLabel.Value ='True';
           else StiffDivider == 1
                app.StiffDividerLabel.Value ='False';
           end

           % Plotting Q-Point
           % DC Load Line 
           dcx = [app.VCEcutoff.Value 0];
           dcy = [0 app.ICsaturation.Value];
           
           % AC Load Line
           acx = [app.ACcutoff.Value 0];
           acy = [0 app.ACsaturation.Value];
           
           %Plots on Same Graph
           plot(app.UIAxes, dcx, dcy, acx, acy);

        
           % Plotting the Input and Output Waveforms
           T1 = app.Duration.Value;
           fs1 = 50*app.Frequency.Value;
           Ts1 = 1/fs1;
           t1  = 0:Ts1:T1;
           a1 = app.Vs.Value*sin(2*pi*app.Frequency.Value*t1);
           a2 = -1*app.Vout.Value*sin(2*pi*app.Frequency.Value*t1);
           plot(app.UIAxes2, t1,a1,t1,a2);
   
        end

        % Callback function
        function UITableDisplayDataChanged(app, event)

        end

        % Value changed function: StiffDividerLabel
        function StiffDividerLabelValueChanged(app, event)
       
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {755, 755};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {722, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Swamped CE Amplifier Picture.jpg');
            app.VCCLabel = uilabel(app.LeftPanel);
            app.VCCLabel.Text = 'Voltage (VCC)';
            app.VCC = uieditfield(app.LeftPanel, 'numeric');
            app.VCC.Value = 20;
            app.VsVppLabel = uilabel(app.LeftPanel);
            app.VsVppLabel.Text = 'Signal Amplitude (Vpp)';
            app.Vs = uieditfield(app.LeftPanel, 'numeric');
            app.Vs.Value = 0.02;
            app.FrequencyHzEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.Frequency = uieditfield(app.LeftPanel, 'numeric');
            app.Frequency.Value = 1000;
            app.DurationsEditFieldLabel = uilabel(app.LeftPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.Duration = uieditfield(app.LeftPanel, 'numeric');
            app.Duration.Value = 0.002;
            app.BetaEditFieldLabel = uilabel(app.LeftPanel);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.Beta = uieditfield(app.LeftPanel, 'numeric');
            app.Beta.Value = 380;
            app.SeriesResistanceLabel = uilabel(app.LeftPanel);
            app.SeriesResistanceLabel.Text = 'Series Resistance';
            app.RS = uieditfield(app.LeftPanel, 'numeric');
            app.RS.Value = 8200;
            app.R1EditFieldLabel = uilabel(app.LeftPanel);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1 = uieditfield(app.LeftPanel, 'numeric');
            app.R1.Value = 10000000;
            app.R2EditFieldLabel = uilabel(app.LeftPanel);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2 = uieditfield(app.LeftPanel, 'numeric');
            app.R2.Value = 620000;
            app.RE1EditFieldLabel = uilabel(app.LeftPanel);
            app.RE1EditFieldLabel.Text = 'RE1';
            app.RE1 = uieditfield(app.LeftPanel, 'numeric');
            app.RE1.Value = 270;
            app.RE2EditFieldLabel = uilabel(app.LeftPanel);
            app.RE2EditFieldLabel.Text = 'RE2';
            app.RE2 = uieditfield(app.LeftPanel, 'numeric');
            app.RE2.Value = 430;
            app.RCEditFieldLabel = uilabel(app.LeftPanel);
            app.RCEditFieldLabel.Text = 'RC';
            app.RC = uieditfield(app.LeftPanel, 'numeric');
            app.RC.Value = 24000;
            app.LoadLabel = uilabel(app.LeftPanel);
            app.LoadLabel.Text = 'Load';
            app.RL = uieditfield(app.LeftPanel, 'numeric');
            app.RL.Value = 2700;
            app.VBEditFieldLabel = uilabel(app.LeftPanel);
            app.VBEditFieldLabel.Text = 'VB';
            app.VB = uieditfield(app.LeftPanel, 'numeric');
            app.VEEditFieldLabel = uilabel(app.LeftPanel);
            app.VEEditFieldLabel.Text = 'VE';
            app.VE = uieditfield(app.LeftPanel, 'numeric');
            app.ICEQEditFieldLabel = uilabel(app.LeftPanel);
            app.ICEQEditFieldLabel.Text = 'ICEQ';
            app.ICEQ = uieditfield(app.LeftPanel, 'numeric');
            app.VCEditFieldLabel = uilabel(app.LeftPanel);
            app.VCEditFieldLabel.Text = 'VC';
            app.VC = uieditfield(app.LeftPanel, 'numeric');
            app.VCEQEditFieldLabel = uilabel(app.LeftPanel);
            app.VCEQEditFieldLabel.Text = 'VCEQ';
            app.VCEQ = uieditfield(app.LeftPanel, 'numeric');
            app.reEditFieldLabel = uilabel(app.LeftPanel);
            app.reEditFieldLabel.Text = 'r''e';
            app.re = uieditfield(app.LeftPanel, 'numeric');
            app.rcEditFieldLabel = uilabel(app.LeftPanel);
            app.rcEditFieldLabel.Text = 'rc';
            app.rc = uieditfield(app.LeftPanel, 'numeric');
            app.ZbaseEditFieldLabel = uilabel(app.LeftPanel);
            app.ZbaseEditFieldLabel.Text = 'Zbase';
            app.Zbase = uieditfield(app.LeftPanel, 'numeric');
            app.ZinEditFieldLabel = uilabel(app.LeftPanel);
            app.ZinEditFieldLabel.Text = 'Zin';
            app.Zin = uieditfield(app.LeftPanel, 'numeric');
            app.ZoutEditFieldLabel = uilabel(app.LeftPanel);
            app.ZoutEditFieldLabel.Text = 'Zout';
            app.Zout = uieditfield(app.LeftPanel, 'numeric');
            app.VinLabel = uilabel(app.LeftPanel);
            app.VinLabel.Text = 'Vin';
            app.Vin = uieditfield(app.LeftPanel, 'numeric');
            app.AvEditFieldLabel = uilabel(app.LeftPanel);
            app.AvEditFieldLabel.Text = 'Av';
            app.Av = uieditfield(app.LeftPanel, 'numeric');
            app.REopEditFieldLabel = uilabel(app.LeftPanel);
            app.REopEditFieldLabel.Text = 'REop';
            app.REop = uieditfield(app.LeftPanel, 'numeric');
            app.MPPEditFieldLabel = uilabel(app.LeftPanel);
            app.MPPEditFieldLabel.Text = 'MPP';
            app.MPP = uieditfield(app.LeftPanel, 'numeric');
            app.IbiasEditFieldLabel = uilabel(app.LeftPanel);
            app.IbiasEditFieldLabel.Text = 'Ibias';
            app.Ibias = uieditfield(app.LeftPanel, 'numeric');
            app.ISLabel = uilabel(app.LeftPanel);
            app.ISLabel.Text = 'IS';
            app.IS = uieditfield(app.LeftPanel, 'numeric');
            app.PDEditFieldLabel = uilabel(app.LeftPanel);
            app.PDEditFieldLabel.Text = 'PD';
            app.PD = uieditfield(app.LeftPanel, 'numeric');
            app.PLEditFieldLabel = uilabel(app.LeftPanel);
            app.PLEditFieldLabel.Text = 'PL';
            app.PL = uieditfield(app.LeftPanel, 'numeric');
            app.PSEditFieldLabel = uilabel(app.LeftPanel);
            app.PSEditFieldLabel.Text = 'PS';
            app.PS = uieditfield(app.LeftPanel, 'numeric');
            app.EfficiencyEditFieldLabel = uilabel(app.LeftPanel);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.Efficiency = uieditfield(app.LeftPanel, 'numeric');
            app.VoutLabel = uilabel(app.LeftPanel);
            app.VoutLabel.Text = 'Vout';
            app.Vout = uieditfield(app.LeftPanel, 'numeric');
            app.DCAnalysisLabel = uilabel(app.LeftPanel);
            app.DCAnalysisLabel.Text = 'DC Analysis';
            app.ACAnalysisLabel = uilabel(app.LeftPanel);
            app.ACAnalysisLabel.Text = 'AC Analysis';
            app.CurrentDrainLabel = uilabel(app.LeftPanel);
            app.CurrentDrainLabel.Text = 'Current Drain';
            app.PowerandEfficiencyLabel = uilabel(app.LeftPanel);
            app.PowerandEfficiencyLabel.Text = 'Power and Efficiency';
            app.MaximumOutputSwingLabel = uilabel(app.LeftPanel);
            app.MaximumOutputSwingLabel.Text = 'Maximum Output Swing';
            app.OptimizingQPointLabel = uilabel(app.LeftPanel);
            app.OptimizingQPointLabel.Text = 'Optimizing Q-Point';
            app.VALabel = uilabel(app.LeftPanel);
            app.VALabel.Text = 'Early Voltage';
            app.VA = uieditfield(app.LeftPanel, 'numeric');
            app.VA.Value = 100;
            app.VCEcutoffEditFieldLabel = uilabel(app.LeftPanel);
            app.VCEcutoffEditFieldLabel.Text = 'VCEcutoff';
            app.VCEcutoff = uieditfield(app.LeftPanel, 'numeric');
            app.ICsaturationEditFieldLabel = uilabel(app.LeftPanel);
            app.ICsaturationEditFieldLabel.Text = 'ICsaturation';
            app.ICsaturation = uieditfield(app.LeftPanel, 'numeric');
            app.ACcutoffEditFieldLabel = uilabel(app.LeftPanel);
            app.ACcutoffEditFieldLabel.Text = 'ACcutoff';
            app.ACcutoff = uieditfield(app.LeftPanel, 'numeric');
            app.ACsaturationEditFieldLabel = uilabel(app.LeftPanel);
            app.ACsaturationEditFieldLabel.Text = 'ACsaturation';
            app.ACsaturation = uieditfield(app.LeftPanel, 'numeric');
            app.DCLoadlineLabel = uilabel(app.LeftPanel);
            app.DCLoadlineLabel.Text = 'DC Loadline';
            app.ACLoadlineLabel = uilabel(app.LeftPanel);
            app.ACLoadlineLabel.Text = 'AC Loadline';
            app.QPointLabel = uilabel(app.LeftPanel);
            app.QPointLabel.Text = 'Q-Point';
            app.InputOutputAmplitudeLabel = uilabel(app.LeftPanel);
            app.InputOutputAmplitudeLabel.Text = 'Input & Output Amplitude';
            app.GainLabel = uilabel(app.LeftPanel);
            app.GainLabel.Text = 'Gain';
            app.ImpedanceLabel = uilabel(app.LeftPanel);
            app.ImpedanceLabel.Text = 'Impedance';
            app.BaseEmitterResistanceLabel = uilabel(app.LeftPanel);
            app.BaseEmitterResistanceLabel.Text = 'Base-Emitter Resistance';
            app.SignalImpedanceLabel = uilabel(app.LeftPanel);
            app.SignalImpedanceLabel.Text = 'Signal Impedance (Ohms)';
            app.Zg = uieditfield(app.LeftPanel, 'numeric');
            app.TransistorLabel = uilabel(app.LeftPanel);
            app.TransistorLabel.Text = 'Transistor';
            app.SignalGeneratorLabel = uilabel(app.LeftPanel);
            app.SignalGeneratorLabel.Text = 'Signal Generator';
            app.StiffDividerEditFieldLabel = uilabel(app.LeftPanel);
            app.StiffDividerEditFieldLabel.Text = 'Stiff Divider';
            app.StiffDividerLabel = uieditfield(app.LeftPanel, 'text');
            app.StiffDividerLabel.ValueChangedFcn = createCallbackFcn(app, @StiffDividerLabelValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Q-Point')
            xlabel(app.UIAxes, 'Voltage (V)')
            ylabel(app.UIAxes, 'Current (I)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Input vs. Output Signal')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude (V)')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end